classdef GUInotgooey < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        MoveItButton            matlab.ui.control.Button
        TargetYEditField        matlab.ui.control.NumericEditField
        TargetYEditFieldLabel   matlab.ui.control.Label
        TargetXEditField        matlab.ui.control.NumericEditField
        TargetXEditFieldLabel   matlab.ui.control.Label
        SetPosButton            matlab.ui.control.Button
        CurrentYEditField       matlab.ui.control.NumericEditField
        CurrentYEditFieldLabel  matlab.ui.control.Label
        CurrentXEditField       matlab.ui.control.NumericEditField
        CurrentXEditFieldLabel  matlab.ui.control.Label
        MapLabel                matlab.ui.control.Label
        LoadMap2Button          matlab.ui.control.Button
        LoadMap1Button          matlab.ui.control.Button
        PollSensorsButton       matlab.ui.control.Button
        ReplyLabel              matlab.ui.control.Label
    end

    
    properties (Access = public)
        robotClient
        map
        xpos
        ypos
        targetx
        targety
    end

    methods (Access = public)
        function setResponse(app, response)
            try
                set(app.ReplyLabel, 'Text', response);
            catch
                %no response
            end
        end
        function setMapDisplay(app, response)
            try
                set(app.MapLabel, 'Text', response);
            catch
                %no response
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.xpos = 1;
            app.ypos = 1;
            
            % Create and connect robot client
            app.robotClient = RobotClient();
            [app.robotClient, response] = app.robotClient.connect();
            setResponse(app, response);
            
            % Get initial position from robot
            if app.robotClient.isConnected
                [app.robotClient, success, msg] = app.robotClient.getCurrentPosition();
                if success
                    app.xpos = app.robotClient.currentX;
                    app.ypos = app.robotClient.currentY;
                    app.CurrentXEditField.Value = app.xpos;
                    app.CurrentYEditField.Value = app.ypos;
                end
            end
        end

        % Button pushed function: PollSensorsButton
        function PollSensorsButtonPushed(app, event)
            response = app.robotClient.pollSensors();
            setResponse(app, response);
            
            % Also update position
            [app.robotClient, success, msg] = app.robotClient.getCurrentPosition();
            if success
                app.xpos = app.robotClient.currentX;
                app.ypos = app.robotClient.currentY;
                app.CurrentXEditField.Value = app.xpos;
                app.CurrentYEditField.Value = app.ypos;
                setMapDisplay(app, RobotMapUtils.printwallmap(app.map, app.xpos, app.ypos));
                
                % Display error in response
                errorMsg = sprintf("%s | Error: %.2f", msg, app.robotClient.positionError);
                setResponse(app, errorMsg);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
             app.robotClient.disconnect();
            delete(app)
        end

        % Button pushed function: LoadMap1Button
        function LoadMap1ButtonPushed(app, event)
            app.map = RobotMapUtils.top2walls([9 5 5 3; 14 15 15 10; 15 9 5 6; 13 6 15 15]);
            setMapDisplay(app, RobotMapUtils.printwallmap(app.map, app.xpos, app.ypos));
        end

        % Button pushed function: LoadMap2Button
        function LoadMap2ButtonPushed(app, event)
            app.map = RobotMapUtils.top2walls([11 15 15 11; 8 5 5 2; 10 15 15 10; 14 13 5 6]);
            setMapDisplay(app, RobotMapUtils.printwallmap(app.map, app.xpos, app.ypos));
        end

        % Button pushed function: SetPosButton
        function SetPosButtonPushed(app, event)
            targetX = app.CurrentXEditField.Value;
            targetY = app.CurrentYEditField.Value;
            
            % Send position to robot for calibration
            [app.robotClient, success, msg] = app.robotClient.setPosition(targetX, targetY);
            
            if success
                app.xpos = app.robotClient.currentX;
                app.ypos = app.robotClient.currentY;
                setMapDisplay(app, RobotMapUtils.printwallmap(app.map, app.xpos, app.ypos));
                setResponse(app, msg);
            else
                setResponse(app, sprintf("Failed to set position: %s", msg));
            end
        end

        % Button pushed function: MoveItButton
        function MoveItButtonPushed(app, event)
            app.targetx = app.TargetXEditField.Value;
            app.targety = app.TargetYEditField.Value;
            
            % Calculate path
            nodelist = PathPlanner.bfs(app.map, app.xpos, app.ypos, app.targetx, app.targety);
            
            % Send path to robot

            success = 0;
            if ~isequal(nodelist, [app.xpos; app.ypos])
                [app.robotClient, success, msg] = app.robotClient.followPath(nodelist);
            else
                msg = "Why'd you send the robot into the wall you bozo?";
            end
            
            if success
                % Update position from robot's actual position
                app.xpos = app.robotClient.currentX;
                app.ypos = app.robotClient.currentY;
                app.CurrentXEditField.Value = app.xpos;
                app.CurrentYEditField.Value = app.ypos;
                
                setMapDisplay(app, RobotMapUtils.printwallmap(app.map, app.xpos, app.ypos));
                setResponse(app, sprintf("%s | Error: %.2f", msg, app.robotClient.positionError));
            else
                setResponse(app, sprintf("Movement failed: %s", msg));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ReplyLabel = uilabel(app.UIFigure);
            app.ReplyLabel.Text = 'Reply';
            app.PollSensorsButton = uibutton(app.UIFigure, 'push');
            app.PollSensorsButton.ButtonPushedFcn = createCallbackFcn(app, @PollSensorsButtonPushed, true);
            app.PollSensorsButton.Text = 'PollSensors';
            app.LoadMap1Button = uibutton(app.UIFigure, 'push');
            app.LoadMap1Button.ButtonPushedFcn = createCallbackFcn(app, @LoadMap1ButtonPushed, true);
            app.LoadMap1Button.Text = 'Load Map 1';
            app.LoadMap2Button = uibutton(app.UIFigure, 'push');
            app.LoadMap2Button.ButtonPushedFcn = createCallbackFcn(app, @LoadMap2ButtonPushed, true);
            app.LoadMap2Button.Text = 'Load Map 2';
            app.MapLabel = uilabel(app.UIFigure);
            app.MapLabel.Text = 'Map';
            app.CurrentXEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentXEditFieldLabel.Text = 'Current X:';
            app.CurrentXEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentXEditField.Limits = [1 5];
            app.CurrentXEditField.RoundFractionalValues = 'on';
            app.CurrentXEditField.Value = 1;
            app.CurrentYEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentYEditFieldLabel.Text = 'Current Y:';
            app.CurrentYEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentYEditField.Limits = [1 5];
            app.CurrentYEditField.RoundFractionalValues = 'on';
            app.CurrentYEditField.Value = 1;
            app.SetPosButton = uibutton(app.UIFigure, 'push');
            app.SetPosButton.ButtonPushedFcn = createCallbackFcn(app, @SetPosButtonPushed, true);
            app.SetPosButton.Text = 'Set Pos';
            app.TargetXEditFieldLabel = uilabel(app.UIFigure);
            app.TargetXEditFieldLabel.Text = 'Target X:';
            app.TargetXEditField = uieditfield(app.UIFigure, 'numeric');
            app.TargetXEditField.Limits = [1 5];
            app.TargetXEditField.RoundFractionalValues = 'on';
            app.TargetXEditField.Value = 1;
            app.TargetYEditFieldLabel = uilabel(app.UIFigure);
            app.TargetYEditFieldLabel.Text = 'Target Y:';
            app.TargetYEditField = uieditfield(app.UIFigure, 'numeric');
            app.TargetYEditField.Limits = [1 5];
            app.TargetYEditField.RoundFractionalValues = 'on';
            app.TargetYEditField.Value = 1;
            app.MoveItButton = uibutton(app.UIFigure, 'push');
            app.MoveItButton.ButtonPushedFcn = createCallbackFcn(app, @MoveItButtonPushed, true);
            app.MoveItButton.Text = 'MoveIt!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUInotgooey

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end